classdef cagecount_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        CagecountTab        matlab.ui.container.Tab
        SummaryofcagecountsperlineforeachmanagerPanel  matlab.ui.container.Panel
        InstructionsTab     matlab.ui.container.Tab
        TopicDropDownLabel  matlab.ui.control.Label
        TopicDropDown       matlab.ui.control.DropDown
        LoadcagelistButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadcagelistButton
        function LoadcagelistButtonPushed(app, event)
            cage_count_gui
            function cage_count_gui
                % clear all
                [sheet_name, data_dir] = uigetfile('*.csv');
                % where data is saved
                % data_dir = 'C:\Users\alexd\Downloads';
                % data_dir = pwd;
                % name of csv file containing cage list
                % sheet_name = 'download_cageList (6).csv';
                
                %% get data and run analysis
                cd(data_dir)
                % read table in
                data = readtable([data_dir,'\',sheet_name],'ReadVariableNames',1);
                
                mouselines = unique(data.ColonyPrefix);
                
                for i = 1 : length(mouselines)
                    
                    % Colony names
                    output{i,1} = mouselines{i};
                    % 1 number of cages per line (sorted by most first)
                    output{i,2} = sum(strcmp(data.ColonyPrefix,mouselines{i}));
                    
                    % get index of rows correspond to line i
                    idx = find(strcmp(data.ColonyPrefix,mouselines{i})==1);
                    
                    % check if there are protocol codes missing
                    for j = 1 : length(idx)
                        missing_protocol_code(j) = isempty(data.ProtocolCode{idx(j)});
                    end
                    
                    % determine which protocol code comes under whoich category
                    test_breedingcodes = eval(    ['contains(data.ProtocolCode(idx),"01") |', ...
                        'contains(data.ProtocolCode(idx),"11") | contains(data.ProtocolCode(idx),"12") |',...
                        'transpose(missing_protocol_code)'] );
                    
                    test_experimentcodes = eval(    ['contains(data.ProtocolCode(idx),"02") | contains(data.ProtocolCode(idx),"03") | contains(data.ProtocolCode(idx),"04")  |', ...
                        'contains(data.ProtocolCode(idx),"05") | contains(data.ProtocolCode(idx),"06") | contains(data.ProtocolCode(idx),"07")  |', ...
                        'contains(data.ProtocolCode(idx),"08") | contains(data.ProtocolCode(idx),"09") | contains(data.ProtocolCode(idx),"10")  |', ...
                        'contains(data.ProtocolCode(idx),"13") | contains(data.ProtocolCode(idx),"14")' ] );
                    
                    clear missing_protocol_code
                    
                    % 2 number of mating cages
                    output{i,3} = sum(strcmp(data.CageFunction(idx),'Mating'));
                    
                    % 3 number of stock male cages
                    output{i,4} = sum(strcmp(data.CageFunction(idx),'Stock') & strcmp(data.CageSex(idx),'Male'));
                    
                    % 4 number of stock females cages
                    n_stock_female_cages = sum(strcmp(data.CageFunction(idx),'Stock') & strcmp(data.CageSex(idx),'Female'));
                    output{i,5} = n_stock_female_cages;
                    
                    % 5 number of single housed males
                    output{i,6} = sum( data.Animals(idx)==1 & strcmp(data.CageSex(idx),'Male'));
                    
                    % 6 number of excess female cages (please consolidate)]
                    % get index of stock female cages and sum the number of females
                    f_idx = idx(strcmp(data.CageFunction(idx),'Stock') & strcmp(data.CageSex(idx),'Female'));
                    n_stock_females = sum( data.Animals(f_idx) );
                    n_female_cages_required = ceil(n_stock_females / 5);
                    output{i,7} = n_stock_female_cages - n_female_cages_required;
                    
                    % 7 number of stock mixed cages
                    output{i,8} = sum(strcmp(data.CageFunction(idx),'Stock') & strcmp(data.CageSex(idx),'Mixed'));
                    
                    % 8 number of single males for breeding (protocols 1,11,12)
                    output{i,9} = sum( data.Animals(idx)==1 & strcmp(data.CageSex(idx),'Male') & ...
                        test_breedingcodes );
                    
                    % 9 "                      for experiments (protocols 2:10,13,14)
                    output{i,10} = sum( data.Animals(idx)==1 & strcmp(data.CageSex(idx),'Male') & ...
                        test_experimentcodes );
                    
                    % 10 breeding stock males
                    output{i,11} = sum(strcmp(data.CageFunction(idx),'Stock') & strcmp(data.CageSex(idx),'Male')                        & test_breedingcodes);
                    
                    % 11 breeding stock females
                    n_stock_female_cages_breeding = sum(strcmp(data.CageFunction(idx),'Stock') & strcmp(data.CageSex(idx),'Female')     & test_breedingcodes);
                    output{i,12} = n_stock_female_cages_breeding;
                    
                    % 12 breeding stock mixed
                    output{i,13} = sum(strcmp(data.CageFunction(idx),'Stock') & strcmp(data.CageSex(idx),'Mixed')                       & test_breedingcodes);
                    
                    % 13 breeding matings
                    output{i,14} = sum(strcmp(data.CageFunction(idx),'Mating')                                                          & test_breedingcodes);
                    
                    % 14 experiment stock males
                    output{i,15} = sum(strcmp(data.CageFunction(idx),'Stock') & strcmp(data.CageSex(idx),'Male')                        & test_experimentcodes);
                    
                    % 15 experiment stock females
                    n_stock_female_cages_experiment = sum(strcmp(data.CageFunction(idx),'Stock') & strcmp(data.CageSex(idx),'Female')   & test_experimentcodes);
                    output{i,16} = n_stock_female_cages_experiment;
                    
                    % 16 experiment stock mixed
                    output{i,17} = sum(strcmp(data.CageFunction(idx),'Stock') & strcmp(data.CageSex(idx),'Mixed')                       & test_experimentcodes);
                    
                    % 17 experiment matings
                    output{i,18} = sum(strcmp(data.CageFunction(idx),'Mating')                                                          & test_experimentcodes);
                    
                    % 18 number of excess female cages for breeding
                    f_idx_breeding = idx(strcmp(data.CageFunction(idx),'Stock') & strcmp(data.CageSex(idx),'Female')                    & test_breedingcodes);
                    n_stock_females_breeding = sum( data.Animals(f_idx_breeding) );
                    n_female_cages_required_breeding = ceil(n_stock_females_breeding / 5);
                    output{i,19} = n_stock_female_cages_breeding - n_female_cages_required_breeding;
                    
                    % 19 number of excess female cages for experiment
                    f_idx_experiment = idx(strcmp(data.CageFunction(idx),'Stock') & strcmp(data.CageSex(idx),'Female')                  & test_experimentcodes);
                    n_stock_females_experiment = sum( data.Animals(f_idx_experiment) );
                    n_female_cages_required_experiment = ceil(n_stock_females_experiment / 5);
                    output{i,20} = n_stock_female_cages_experiment - n_female_cages_required_experiment;
                    
                end
                
                data_sorted = sortrows(output,[2 7 6 1],'descend');
                
                %% plot number of cages of each type
                % Set screen size
                % figure('WindowState','maximized')
                f = figure('Position', [6          66        1273         575]);
                % Set aesthetic defaults
                MEAgraphics(1)
                
                tiledlayout(1,2);
                nexttile
                
                % determine colours;
                % colour for each cage type
                % shade for experiment vs breeding
                cmap            = jet(16);
                % barcols     = [3:4:15;4:4:16];
                % barcols     = barcols(:);
                barcols         = cmap([4 3 8 7 11 12 15 16],:);
                barcols(4,:)    = barcols(4,:) - [0.25 0.25 0.35];
                barcols = barcols([7 8 3 4 5 6 1 ],:);
                % stock f, m, mixed, mating; then repeat for experiment
                % y = [cell2mat(data_sorted(:,12)) , cell2mat(data_sorted(:,11)) , cell2mat(data_sorted(:,13)) , cell2mat(data_sorted(:,14)),...
                %     cell2mat(data_sorted(:,16)) , cell2mat(data_sorted(:,15)) , cell2mat(data_sorted(:,17)) , cell2mat(data_sorted(:,18))];
                y1 = [cell2mat(data_sorted(:,12)) , cell2mat(data_sorted(:,16)) , cell2mat(data_sorted(:,11)) , cell2mat(data_sorted(:,15)),...
                    cell2mat(data_sorted(:,13)) , cell2mat(data_sorted(:,17)) , cell2mat(data_sorted(:,14)) ];
                % stack bar plot of number of mating cages, n. male stock, n. female stock
                b=bar( y1 , 'stacked','FaceColor','flat');
                for k=1:size(y1,2)
                    b(k).CData = barcols(k,:);
                end
                
                xticks(1:size(data_sorted,1));
                xticklabels( data_sorted(:,1) );
                xtickangle(45)
                yticks(0:1: max([data_sorted{:,2}]) )
                ylabel('Total cages')
                ylim([0 max(sum(y1,2))+3])
                aesthetics_cc
                title({[num2str(height(data)) ' cages (x = line, colour = cage type (by protocol))'],''})
                
                plotmanagers(y1,y1)
                
                l = legend({[char(9792) ' Stock ' ],[char(9792) ' Experiment '],...
                    [char(9794) ' Stock ' ],[char(9794) ' Experiment ']...
                    [char(9792) '+' char(9794) ' Stock '],[char(9792) '+' char(9794) ' Experiment ']...
                    'Breeding'},...
                    'box','off','FontSize',10,'Location','eastoutside');
                
                set(gca,'FontSize',10)
                
                %% plot task possibilites
                nexttile
                % set possible female consolidations of <0 to 0 (occurs where someone has
                % used a large mouse cage that can have >5 females
                female_tasks                                        = cell2mat(data_sorted(:,7));
                female_tasks(female_tasks<0)                        = 0;
                female_tasks_breeding                               = cell2mat(data_sorted(:,19));
                female_tasks_breeding(female_tasks_breeding<0)      = 0;
                female_tasks_experiment                             = cell2mat(data_sorted(:,20));
                female_tasks_experiment(female_tasks_experiment<0)  = 0;
                
                male_tasks                      = cell2mat(data_sorted(:,6));
                male_breeding                   = cell2mat(data_sorted(:,9));
                male_experiment                 = cell2mat(data_sorted(:,10));
                % could edit here to plot different cage protocols in different colours
                % cmap = lines;
                % cmap = cmap(5:end,:);
                % set colours manually
                barcols = [219 112 147; 200 200 200 ; 139 0.01 0.01 ; 125 125 125]./256 ; % consolidation, breeding SH males, exp. SH males
                
                y2 = [ female_tasks_breeding,female_tasks_experiment,male_breeding,male_experiment ];
                b=bar( y2 , 'stacked','FaceColor','flat');
                for k=1:size(y2,2)
                    b(k).CData = barcols(k,:);
                end
                
                xticks(1:size(data_sorted,1));
                xticklabels( data_sorted(:,1) );
                xtickangle(45)
                yticks(0:1: max(male_tasks + female_tasks) )
                ylabel('Total possible tasks')
                aesthetics_cc
                title({'Possible consolidations and SH males by protocol type',''})
                
                linkaxes
                
                plotmanagers(y2,y1)
                
                l = legend({['Consolidate ' char(9792) ' (stock)'],['Consolidate ' char(9792) ' (experiment)']...
                    ['Single-housed ' char(9794) ' (stock)'],['Single-housed ' char(9794) ' (experiment)']},...
                    'box','off','FontSize',10,'Location','northeastoutside');
                
                set(gca,'FontSize',10)
                
                %% add key for line names
                for L = 1 : length(data_sorted(:,1))
                    idx = find(strcmp(data.ColonyPrefix,data_sorted(L,1)));
                    linename_str = data.ColonyName{idx(1)}   ;
                    linepref_str = data.ColonyPrefix{idx(1)} ;
                    anntn{L,1} = sprintf([linepref_str,': \t\t',linename_str]);
                end
                a = annotation('textbox',[.8 .1 0.5 0.5],'String',anntn,'FitBoxToText','on');
                
                %% save figure and csv
                % save the csv and figure with date and time
                d=datetime;
                n=sprintf('summary_cageList_%04d-%02d-%02d_%02d%02dh',d.Year, d.Month, d.Day, d.Hour, d.Minute);
                saveas(f,n,'jpeg');
                
                n=sprintf('download_cageList_%04d-%02d-%02d_%02d%02dh_sorted',d.Year, d.Month, d.Day, d.Hour, d.Minute);
                writetable(data, [n '.csv']);
                
                %% manager plotting function
                function plotmanagers(bottomY,topY)
                    
                    % add colony managers
                    managers = unique(data.ColonyManager);
                    % choose colours
                    man_cols = gray(length(managers)+2);
                    
                    % tabulate who manages which line
                    manager_table = data_sorted(:,1);
                    for ii=1:length(manager_table)
                        idxs = find(strcmp(data.ColonyPrefix,manager_table{ii})==1);
                        manager_table{ii,2}=data.ColonyManager{idxs(1)}(1: strfind(data.ColonyManager{idxs(1)},' ')-1);
                    end
                    
                    % rank manager list in terms of max. line size
                    managers = unique(manager_table(:,2),'stable');
                    
                    name_gap = 2.5;
                    name_positions = linspace(1,size(data_sorted,1),length(managers));
                    for name = 1:length(managers)
                        t(name) = text(name_positions(name),3+max(sum(topY,2)),managers{name},'FontSize',10,...
                            'HorizontalAlignment','center','Color',man_cols(name,:),'rotation',-30);
                    end
                    
                    
                    % add lines to indicate lines managed
                    yspacing    = .25;
                    name_yadd   = 2.5;
                    ytop1       = max(sum(topY,2))+name_yadd;
                    ytop2       = sum(bottomY,2)+name_yadd;
                    ypos_across = ytop1-yspacing : -yspacing : ytop1 - yspacing*(length(managers));
                    jitter      = linspace(-.1,.1,length(managers)) ;
                    
                    % increase line width
                    set(groot,'defaultLineLineWidth',1.5)
                    
                    for name = 1 : length(managers)
                        managed_lines = manager_table(strcmp(manager_table(:,2),managers{name}),1);
                        
                        % add horizontal lines
                        for ii = 1 : length(managed_lines)
                            xacross2(ii) = find(strcmp(data_sorted(:,1),managed_lines(ii)));
                        end
                        xacross1 = [xacross2 name_positions(name)];
                        hold on
                        plot(jitter(name)+[min(xacross1) max(xacross1)],[ypos_across(name)  ypos_across(name)],'Color',man_cols(name,:))
                        
                        % add vertical lines
                        % add line down from name
                        plot(jitter(name)+[name_positions(name) name_positions(name)] , [ypos_across(name) ytop1+yspacing],'Color',man_cols(name,:))
                        for ii = 1 : length(managed_lines)
                            % add second vertical line for each mouse line
                            plot(jitter(name)+[xacross2(ii) xacross2(ii)] , ...
                                [ytop2(find(strcmp(data_sorted(:,1),managed_lines(ii)))) + yspacing - name_yadd, ypos_across(name)],...
                                'Color',man_cols(name,:))
                        end
                        clear managed_lines xacross1 xacross2
                    end
                    
                    % restore line width
                    set(groot,'defaultLineLineWidth',1)
                    set(gca,'XMinorTick','off','YMinorTick','off');
                end
                
                function aesthetics_cc()
                    set(gca, 'box', 'off') % remove borders
                    set(gcf,'color','w'); % white background
                    set(gca, 'TickDir', 'out')
                    set(gca,'FontSize',14);
                    set(gca,'FontName','Arial');
                    set(gca,'XMinorTick','on','YMinorTick','on');
                    set(gca,'LineWidth',2);
                    ax = gca;
                    ax.TitleFontWeight = 'Normal';
                    try
                        ax.Colorbar.TickDirection = 'out';
                        ax.Colorbar.FontName = 'Arial';
                        ax.Colorbar.FontSize = 14;
                        ax.Colorbar.Label.FontSize = 16;
                        ax.Colorbar.Box = 'off';
                    catch
                    end
                    
                    try
                        ax.Legend.FontName = 'Arial';
                        ax.Legend.FontSize = 14;
                        ax.Legend.FontWeight = 'normal';
                        ax.Legend.Title.FontWeight = 'normal';
                        ax.Legend.Box = 'off';
                    catch
                    end
                    % set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
                    %     'XMinorTick', 'on', 'YMinorTick', 'on', 'YGrid', 'on', ...
                    %     'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', 0:500:2500, ...
                    %     'LineWidth', 1)
                    
                    % set(gca, 'Box', 'off', 'TickDir', 'out',  ...
                    %     'XMinorTick', 'on', 'YMinorTick', 'on', 'YGrid', 'on')
                    set(gca, 'YGrid','off','XGrid','off')
                end
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CagecountTab = uitab(app.TabGroup);
            app.CagecountTab.Title = 'Cage count';
            app.SummaryofcagecountsperlineforeachmanagerPanel = uipanel(app.CagecountTab);
            app.SummaryofcagecountsperlineforeachmanagerPanel.Title = 'Summary of cage counts per line for each manager';
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.TopicDropDownLabel = uilabel(app.InstructionsTab);
            app.TopicDropDownLabel.Text = 'Topic';
            app.TopicDropDown = uidropdown(app.InstructionsTab);
            app.TopicDropDown.Items = {'Acquiring MCMS cage list', 'Running cage count'};
            app.TopicDropDown.Value = 'Acquiring MCMS cage list';
            app.LoadcagelistButton = uibutton(app.UIFigure, 'push');
            app.LoadcagelistButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcagelistButtonPushed, true);
            app.LoadcagelistButton.Text = 'Load cagelist';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cagecount_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end